classdef practica2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        AnteriorButton   matlab.ui.control.Button
        SiguienteButton  matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        contador = 0;
    end
    methods (Access = public)
        %Sen 7
        function valoresFrecCardiacaYActividad = calcFrecCardiaca(app,numValores)
            %Se defina la fecuencia minima y maxima
            minFrecuencia = 60;
            maxFrecuencia = 200;
            %Definimos un incremento definido
            incrementoPorActividad = 9;
            %Se crea un vector para indicar la actividad fisica que vaya del 0 al
            %15
            actividadFisica = 0:numValores-1;
            %matriz para almacenar la actividad y la frec cardiaca
            valoresFrecCardiacaYActividad = zeros(2,numValores);
            %vector para almacenar la fecuencia cardiaca
            valoresFrecCardiaca = zeros(1,numValores);
            %Valores aleatorios para cada numero
            variabilidadIndividual = randi([-2, 15], 1, numValores);
            %Se crea un vector para las fecuencias cardiacas que vaya aumentando a
            for i = 1:numValores
                %se calcula la frecuencia cardiaca
                frecCardiaca = minFrecuencia+(actividadFisica(i)*incrementoPorActividad)+variabilidadIndividual(i);
                %se comprueba que no rebase el limite
                if frecCardiaca >maxFrecuencia
                    frecCardiaca = maxFrecuencia;
                end
                %se almacena la frecuencia cardiaca
                valoresFrecCardiaca(i) = frecCardiaca;
            end
            valoresFrecCardiacaYActividad(1,1:end) = valoresFrecCardiaca;
            valoresFrecCardiacaYActividad(2,1:end) = actividadFisica;
        end
        %Sen 8
        function valoresFloracion = calcFloracion(app,tempMaxima)
            %Se define una temperatura minima
            tempMinima = -5;
            floracion = zeros(1,tempMaxima-tempMinima+1);
            valTemperaturas = -5:tempMaxima;
            valoresFloracion = zeros(2,tempMaxima-tempMinima+1);
            incrementoFlores = 10;
            incrementoAleatorioFlores = randi([0, 30], 1, tempMaxima-tempMinima+1);
            for i =1:tempMaxima-tempMinima+1
                if valTemperaturas(i) < 1
                    floracion(i) = floracion(i)+0;
                elseif valTemperaturas(i) > 35
                    floracion(i) = floracion(i-1)-(incrementoAleatorioFlores(i)*valTemperaturas(i));
                else
                    floracion(i) = floracion(i-1)+(incrementoAleatorioFlores(i)*valTemperaturas(i));
                end
            end
            valoresFloracion(1,1:end) = valTemperaturas;
            valoresFloracion(2,1:end) = floracion;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            app.contador = app.contador+1;
            if app.contador == 1
                %Senal 1
                
            elseif app.contador == 2
                %Senal 2
            elseif app.contador == 3
                %Senal 3
            elseif app.contador == 4
                %Senal 4
            elseif app.contador == 5
                %Senal 5
            elseif app.contador == 6
                %Senal 6
            elseif app.contador == 7
                valoresSen7 = calcFrecCardiaca(app,16);
                plot(app.UIAxes,valoresSen7(2,1:end),valoresSen7(1,1:end),'-o', 'Color', 'red')
            elseif app.contador == 8
                valoresSen8 = calcFloracion(app,45);
                plot(app.UIAxes,valoresSen8(1,1:end),valoresSen8(2,1:end),'-o', 'Color', 'red')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Num. Variables'; 'Dimension'; 'Variables'; 'Valores'; 'Estadisticas'};
            app.UITable.RowName = {};
            app.SiguienteButton = uibutton(app.UIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.AnteriorButton = uibutton(app.UIFigure, 'push');
            app.AnteriorButton.Text = 'Anterior';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end