classdef practica3N < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TipoButtonGroup                matlab.ui.container.ButtonGroup
        AperiodicaButton               matlab.ui.control.RadioButton
        PeriodicaButton                matlab.ui.control.RadioButton
        TiempomaxEditField             matlab.ui.control.NumericEditField
        TiempomaxEditFieldLabel        matlab.ui.control.Label
        TiempominEditField             matlab.ui.control.NumericEditField
        TiempominEditFieldLabel        matlab.ui.control.Label
        FrecuenciademuestreoEditField  matlab.ui.control.NumericEditField
        FrecuenciademuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel         matlab.ui.control.Label
        RepresentacionButtonGroup      matlab.ui.container.ButtonGroup
        DiscretaButton                 matlab.ui.control.RadioButton
        ContinuaButton                 matlab.ui.control.RadioButton
        SealDropDown                   matlab.ui.control.DropDown
        SealDropDownLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function  ploteacion = graficarSenoidal(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            
            % Definición de la matriz de tiempo
            t2 = linspace(tmin, tmax, ((tmax - tmin) * 1000) / app.FrecuenciademuestreoEditField.Value);
    
            y = amplitud*sin(2*pi*frecuencia*t2);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            f_neg_t = amplitud * sin(-2 * pi * frecuencia * t2);
            f_par = 0.5 * (f_t + f_neg_t);    % Componente par
            f_impar = 0.5 * (f_t - f_neg_t);  % Componente impar
            %calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end

        % Triangular
        function  ploteacion = graficarTriangular(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*sawtooth(2*pi*frecuencia*t2,0.5);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            fNeg = amplitud*sawtooth(2*pi*frecuencia*-t2,0.5);
            f_par = 0.5 * (f_t + fliplr(f_t));
            f_impar = 0.5 * (f_t - fliplr(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end

        % Cuadrada
        function  ploteacion = graficarCuadrada(app)
            amplitud = app.AmplitudEditField.Value;
            %periodo = 1/app.FrecuenciaEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            fs =app.FrecuenciademuestreoEditField.Value;
            %t = linspace(tmin,tmax,fs*(tmax-tmin));
            t2 = tmin:fs:tmax;
            y = amplitud*square(2*pi*frecuencia*t2);
            if app.AperiodicaButton.Value == 1
                factorAmortiguacion = 0.01;
                amortiguacion = exp(-factorAmortiguacion * t2);
                y = y .* amortiguacion;
            end
            f_t = y;
            f_par = 0.5 * (f_t + fliplr(f_t));
            f_impar = 0.5 * (f_t - fliplr(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes,t2,y,'LineWidth',1,'Color','r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        % Rampa
        function ploteacion = graficarRampa(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            frecuencia = app.FrecuenciaEditField.Value;
            fs = app.FrecuenciademuestreoEditField.Value;
        
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
        
            % Generar la señal de rampa
            y = amplitud * t2 .* (t2 >= 0);
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_t = y;
            f_par = 0.5 * (f_t + fliplr(f_t));
            f_impar = 0.5 * (f_t - fliplr(f_t));
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            %Calculos
            %E = trapz(t2, y.^2);
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        %Escalon Unitariooo --------------
        function ploteacion = graficarEscalonUnitario(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de escalón unitario
            y = amplitud*heaviside(t2);
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_t = y;
            fNeg = amplitud*heaviside(-t2);
            f_par = 0.5 * (f_t + fNeg);
            f_impar = 0.5 * (f_t - fNeg);
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        
        %Impulso Unitario --------------
        % Función impulso unitario
        function ploteacion = graficarImpulsoUnitario(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de impulso unitario
            y = zeros(size(t2));
            y(t2 == 0) = amplitud;
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_par = y;
            f_impar = zeros(size(y));
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end

        % Pulso Rectangular
        function ploteacion = graficarPulsoRectangular(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            ancho_pulso = 1; % Ancho del pulso
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de pulso rectangular
            y = amplitud * rectangularPulse(t2);
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_t = y;
            f_par = f_t;
            f_impar = zeros(size(t2));
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        %Pulso triangular
        function ploteacion = graficarImpulsoTriangular(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            ancho = 1;
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal de pulso rectangular
            y = amplitud*triangularPulse(t2);
            f_t = y;
            fNeg = amplitud*triangularPulse(-t2);
            f_par = 0.5 * (f_t + fNeg);
            f_impar = 0.5 * (f_t - fNeg);
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        % Exponencial -----------
        function ploteacion = graficarExponencial(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            tau = 1; % Parámetro de tiempo característico
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            
            % Generar la señal exponencial
            y = exp(t2);
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_t = y;
            f_par = 0.5 * (f_t + flip(f_t));
            f_impar = 0.5 * (f_t - flip(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
        function ploteacion = graficarExponencialDec(app)
            amplitud = app.AmplitudEditField.Value;
            tmin = app.TiempominEditField.Value;
            tmax = app.TiempomaxEditField.Value;
            tau = 1; % Parámetro de tiempo característico
            
            % Calcular el vector de tiempo
            t2 = tmin:app.FrecuenciademuestreoEditField.Value:tmax;  % Puedes ajustar el paso (0.01 en este ejemplo) para obtener una representación más suave
            r=1.5;
            xd = exp(t2);
            % Generar la señal exponencial
            y = r.^(-t2);
            if app.PeriodicaButton.Value == 1
                y = mod(y,1/app.FrecuenciaEditField.Value);
            end
            f_t = y;
            f_par = 0.5 * (f_t + flip(f_t));
            f_impar = 0.5 * (f_t - flip(f_t));
            %Calculos
            E = trapz(t2, y.^2);
            % Seleccionar el tipo de gráfica según la opción seleccionada por el usuario
            if app.DiscretaButton.Value == 1
                ploteacion = stem(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                stem(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                stem(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            else
                ploteacion = plot(app.UIAxes, t2, y, 'LineWidth', 1, 'Color', 'r');
                plot(app.UIAxes2,t2,f_par,'LineWidth',1,'Color','r');
                plot(app.UIAxes2_2,t2,f_impar,'LineWidth',1,'Color','r');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            switch app.SealDropDown.Value
                case 'Senoidal'
                    graficarSenoidal(app)
                case 'Triangular'
                    graficarTriangular(app)
                case 'Cuadrada'
                    graficarCuadrada(app)
                case 'Rampa'
                    graficarRampa(app)
                case 'Escalon unitario'
                    graficarEscalonUnitario(app);
                case 'Impulso unitario'
                    graficarImpulsoUnitario(app);
                case 'Impulso rectangular'
                    graficarPulsoRectangular(app);
                case 'Impulso triangular'
                    graficarImpulsoTriangular(app);
                case 'Exponencial'
                    graficarExponencial(app);
                case 'Exp. decreciente'
                    graficarExponencialDec(app);
                otherwise
                %%PAra más funciones
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, 'Impar')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Par')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SealDropDownLabel = uilabel(app.GridLayout);
            app.SealDropDownLabel.Text = 'Señal ';
            app.SealDropDown = uidropdown(app.GridLayout);
            app.SealDropDown.Items = {'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalon unitario', 'Impulso unitario', 'Impulso triangular', 'Impulso rectangular', 'Exp. decreciente'};
            app.SealDropDown.Value = 'Senoidal';
            app.RepresentacionButtonGroup = uibuttongroup(app.GridLayout);
            app.RepresentacionButtonGroup.Title = 'Representacion';
            app.ContinuaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.ContinuaButton.Value = true;
            app.DiscretaButton = uiradiobutton(app.RepresentacionButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudEditField.Value = 1;
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciaEditField.Value = 1;
            app.FrecuenciademuestreoEditFieldLabel = uilabel(app.GridLayout);
            app.FrecuenciademuestreoEditFieldLabel.Text = 'Frecuencia de muestreo';
            app.FrecuenciademuestreoEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrecuenciademuestreoEditField.Value = 0.2;
            app.TiempominEditFieldLabel = uilabel(app.GridLayout);
            app.TiempominEditFieldLabel.Text = 'Tiempo min';
            app.TiempominEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditFieldLabel = uilabel(app.GridLayout);
            app.TiempomaxEditFieldLabel.Text = 'Tiempo max';
            app.TiempomaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempomaxEditField.Value = 10;
            app.TipoButtonGroup = uibuttongroup(app.GridLayout);
            app.TipoButtonGroup.Title = 'Tipo';
            app.PeriodicaButton = uiradiobutton(app.TipoButtonGroup);
            app.PeriodicaButton.Text = 'Periodica';
            app.PeriodicaButton.Value = true;
            app.AperiodicaButton = uiradiobutton(app.TipoButtonGroup);
            app.AperiodicaButton.Text = 'Aperiodica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica3N

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end